classdef demoDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        single_simulate                 matlab.ui.container.toolbar.PushTool
        continue_simulate               matlab.ui.container.toolbar.PushTool
        stop_simulate                   matlab.ui.container.toolbar.PushTool
        Panel_9                         matlab.ui.container.Panel
        log_area                        matlab.ui.control.TextArea
        Panel_8                         matlab.ui.container.Panel
        Pd_GLRT                         matlab.ui.control.Label
        Pd_MME                          matlab.ui.control.Label
        Pd_ED                           matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        test_statistics_GLRT            matlab.ui.control.LinearGauge
        GLRTLabel                       matlab.ui.control.Label
        MMELabel                        matlab.ui.control.Label
        test_statistics_MME             matlab.ui.control.LinearGauge
        EDLabel                         matlab.ui.control.Label
        test_statistics_ED              matlab.ui.control.LinearGauge
        Panel_5                         matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        Tab_7                           matlab.ui.container.Tab
        SNRs                            matlab.ui.control.Label
        Tab_4                           matlab.ui.container.Tab
        Panel_7                         matlab.ui.container.Panel
        set_click_rec_node_position     matlab.ui.control.StateButton
        input_rec_node_num              matlab.ui.control.DropDown
        Label_13                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        set_rec_node_position           matlab.ui.control.Button
        input_center_y                  matlab.ui.control.NumericEditField
        Label_11                        matlab.ui.control.Label
        input_center_x                  matlab.ui.control.NumericEditField
        Label_10                        matlab.ui.control.Label
        input_rec_node_dir              matlab.ui.control.NumericEditField
        Label_9                         matlab.ui.control.Label
        input_rec_node_range            matlab.ui.control.NumericEditField
        kmLabel                         matlab.ui.control.Label
        input_rec_node_num_2            matlab.ui.control.DropDown
        Label_8                         matlab.ui.control.Label
        input_formation                 matlab.ui.control.DropDown
        Label_7                         matlab.ui.control.Label
        Tab_5                           matlab.ui.container.Tab
        force_monte_carlo               matlab.ui.control.CheckBox
        input_threshold_from_localfile  matlab.ui.control.Button
        input_pf                        matlab.ui.control.NumericEditField
        Label_18                        matlab.ui.control.Label
        input_MC                        matlab.ui.control.NumericEditField
        Label_17                        matlab.ui.control.Label
        auto_set_threshold              matlab.ui.control.Button
        set_threshold_file_path         matlab.ui.control.Button
        threshold_file_path             matlab.ui.control.EditField
        Label_16                        matlab.ui.control.Label
        Tab_6                           matlab.ui.container.Tab
        noise_fluc                      matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        input_ref_SNR                   matlab.ui.control.Slider
        Label_6                         matlab.ui.control.Label
        Tab_2                           matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        Label_5                         matlab.ui.control.Label
        setClickXY                      matlab.ui.control.StateButton
        Panel_3                         matlab.ui.container.Panel
        input_y                         matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        setInputXY                      matlab.ui.control.Button
        input_x                         matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        Tab_3                           matlab.ui.container.Tab
        input_mod_type                  matlab.ui.control.DropDown
        Label_14                        matlab.ui.control.Label
        input_fc                        matlab.ui.control.NumericEditField
        HzEditField_3Label              matlab.ui.control.Label
        input_obs_time                  matlab.ui.control.NumericEditField
        sEditFieldLabel                 matlab.ui.control.Label
        input_baud_rate                 matlab.ui.control.NumericEditField
        HzEditField_2Label              matlab.ui.control.Label
        input_sample_rate               matlab.ui.control.NumericEditField
        HzEditFieldLabel                matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        Coordinate                      matlab.ui.control.UIAxes
    end


    properties (Access = private)

        % for source node
        is_source_node_position_ready logical = true;
        source_node_position double = zeros(1, 2)
        % sourcePointHandles matlab.graphics.chart.primitive.Line % plot handles for source node (normal point)
        sourcePointHandles matlab.graphics.primitive.Image  % (Image Icon mode)

        % for receive nodes
        is_rec_node_position_ready logical = true;
        rec_node_num uint8 = 3;
        rec_node_num_valid uint8 = 0;  % this is useful when assign rec node position by click
        rec_node_position double = [];
        rec_node_snrs double = [];
        % recPointHandles matlab.graphics.chart.primitive.Line % plot handles for receive node (normal point)
        recPointHandles matlab.graphics.primitive.Image

        % for test statistics result
        res_ts double = zeros(3, 1);  % order: ED, MME, GLRT
        thresholds double = zeros(3, 1);
        pd double = zeros(3, 1);

        % for simulation running
        simulation_running logical = false;

        % log history
        log_history = {};
    end

    methods (Access = private)

        function refreshCoordinate(app)

            hold(app.Coordinate, 'on');

            % plot source node position in the figure
            delete(app.sourcePointHandles);
            % app.sourcePointHandles = plot(app.Coordinate, app.source_node_position(1), app.source_node_position(2), 'pr', 'MarkerSize', 10);
            app.sourcePointHandles = plotSourceIcon(app, app.source_node_position);
            
            % plot receive nodes position in the figure
            delete(app.recPointHandles);
            % app.recPointHandles = plot(app.Coordinate, app.rec_node_position(:, 1), app.rec_node_position(:, 2),  '^r', 'MarkerFaceColor', 'r', 'MarkerSize', 6);
            app.recPointHandles = plotRecNodeIcon(app, app.rec_node_position);
            hold(app.Coordinate, 'off');
        end

        
        function pushLogMsg(app, msg)

            % update messages
            app.log_history = [app.log_history, {[datestr(now, 'yyyy-mm-dd HH:MM:SS'), '>> ' , msg]}];
            app.log_area.Value = app.log_history;
            
            % scroll to bottom
            app.log_area.scroll("bottom");
        end
        
        function singleSimulation(app)

            % compute SNR and DELAY by positions
            ref_snr = app.input_ref_SNR.Value;
            source_position = app.source_node_position .* 1000;
            rec_node_position = app.rec_node_position .* 1000;

            [delays, snrs] = delayAndSNR(ref_snr, source_position, rec_node_position);
            app.rec_node_snrs = snrs;
            app.SNRs.Text = [sprintf('信噪比\n'), sprintf('%.2f\n', snrs)];

            % % signal generation setting
            % sample_rate = app.input_sample_rate.Value; %Hz
            % obser_time  = app.input_obs_time.Value; % s
            % baud_rate   = app.input_baud_rate.Value;
            % fc          = app.input_fc.Value; % carrier frequency
            % modu_type   = app.input_mod_type.Value;
            % 
            % % at transmitter: generate baseband signal
            % SFS = round(sample_rate/baud_rate); % samples per symbol
            % ModuSig = helperModClassGetModulator(modu_type, SFS, sample_rate);
            % NST = round(obser_time*sample_rate); % samples per frame
            % SigSrc = helperModClassGetSource(modu_type, SFS, NST, sample_rate);
            % sigsrc = SigSrc(); % generate new symbol signal
            % sigtmp_tx = ModuSig(sigsrc); % generate modulated signal
            % 
            % % at receivers: generate received signal
            % fd = zeros(1, app.rec_node_num);   % frequency shift set to 0 for all
            % alpha =  10.^(unifrnd(-app.noise_fluc.Value, app.noise_fluc.Value, app.rec_node_num, 1)/10); % noise power 
            % rec_node_data = [];
            % for i = 1:app.rec_node_num
            %     sig_data = nodeSigGen(fc, sample_rate, sigtmp_tx, delays(i), fd(i), snrs(i), alpha(i));
            %     rec_node_data = [rec_node_data, sig_data.signal];
            % end

            %%%%%%%%%%%%%%%% old version for generating data %%%%%%%%%%%%%
            sample_rate = app.input_sample_rate.Value; %Hz
            obser_time  = app.input_obs_time.Value; % s
            baud_rate   = app.input_baud_rate.Value;
            Fc          = app.input_fc.Value; % carrier frequency
            modu_type   = app.input_mod_type.Value;
            alpha =  10.^(unifrnd(-app.noise_fluc.Value, app.noise_fluc.Value, app.rec_node_num, 1)/10); % noise power 

            tmp = nodeDataGene(Fc, modu_type, baud_rate, sample_rate, obser_time, delays, snrs, alpha);
            X = tmp.signal;

            % compute test statistics
            app.res_ts = [ED(X); MME(X); GLR(X, 1, [-1, -1])];

            % refresh results
            refreshTestStatistics(app);

          
        end
        
        function refreshTestStatistics(app)

            app.test_statistics_ED.Value   = app.res_ts(1);
            app.test_statistics_MME.Value  = app.res_ts(2);
            app.test_statistics_GLRT.Value = app.res_ts(3);
            
        end
        
        function hashStr = matlabStruct2HashStr(~, dataStruct)
            % generated by GPT-4

            % 将结构体转换为 JSON 字符串
            jsonData = jsonencode(dataStruct);
            % 转换为 Java 字节数组
            dataBytes = java.lang.String(jsonData).getBytes('UTF-8');

            % 初始化 Java 的 MessageDigest 类实例使用 SHA-256 哈希算法
            md = java.security.MessageDigest.getInstance('SHA-256');

            % 对数据进行哈希处理
            md.update(dataBytes);

            % 获取哈希字节数据并转换为十六进制字符串
            digest = md.digest();
            hashStr = sprintf('%02x', typecast(int8(digest), 'uint8'));

        end
        
        function thresholds = findThresholdsByMonteCarlo(app)

            % input monte carlo number and probability of false alarm rate
            Monte_Carlo = app.input_MC.Value;
            pf = app.input_pf.Value;

            % stimulate pure noise by putting rece node at far field
            rec_node_position_snapshot = app.rec_node_position;
            app.rec_node_position = app.rec_node_position * 0 - 1000;

            % main process
            h = waitbar(0, '正在【纯噪声】模拟条件下寻找门限值...');

            ts_noise = [];
            for counter = 1 : Monte_Carlo
                if mod(counter, 10) == 0
                    waitbar(counter / Monte_Carlo, h);
                end
                singleSimulation(app);
                ts_noise = [ts_noise; app.res_ts'];
            end

            close(h);

            % recover the original rec node positions
            app.rec_node_position = rec_node_position_snapshot;

            % decide thresholds
            for i = 1:3
                app.thresholds(i) = quantile(ts_noise(:, i), 1 - pf);
            end

            % save results
            thresholds = app.thresholds;
        end
        
        function icon_handle = plotSourceIcon(app, position)
            % generated by GPT-4
            % revised by Rui Zhou

            % 读取图片和透明度数据
            [iconData, ~, alphaData] = imread('icons/fighter-jet.png');
            iconData = flipud(iconData);
            alphaData = flipud(alphaData);

            % 缩放图片和透明度数据到所需的大小
            scaleFactor = 1; % 缩放比例因子，例如缩小为原来的50%
            iconDataResized = imresize(iconData, scaleFactor);
            alphaDataResized = imresize(alphaData, scaleFactor);

            % 指定要放置图标的位置（以数据单位表示）
            positionX = position(1) - 4; % X轴位置
            positionY = position(2) - 5; % Y轴位置
            

            % 显示图标
            icon_handle = image(app.Coordinate, 'CData', iconDataResized, ...
                'XData', [positionX positionX + 8], ...
                'YData', [positionY positionY + 10], ...
                'AlphaData', alphaDataResized);
        end


        function icon_handle = plotRecNodeIcon(app, position)
            % generated by GPT-4
            % revised by Rui Zhou

            icon_handle = [];

            % 读取图片和透明度数据
            [iconData, ~, alphaData] = imread('icons/radar.png');
            iconData = flipud(iconData);
            alphaData = flipud(alphaData);

            % 缩放图片和透明度数据到所需的大小
            scaleFactor = 1; % 缩放比例因子，例如缩小为原来的50%
            iconDataResized = imresize(iconData, scaleFactor);
            alphaDataResized = imresize(alphaData, scaleFactor);


            if (size(position, 1) == 1)
                
                % 指定要放置图标的位置（以数据单位表示）
                positionX = position(1) - 3; % X轴位置
                positionY = position(2) - 4; % Y轴位置

                % 显示图标
                icon_handle = image(app.Coordinate, 'CData', iconDataResized, ...
                    'XData', [positionX positionX + 6], ...
                    'YData', [positionY positionY + 6], ...
                    'AlphaData', alphaDataResized);
            else
                for i = 1:size(position, 1)
                    % 指定要放置图标的位置（以数据单位表示）
                    positionX = position(i, 1) - 3; % X轴位置
                    positionY = position(i, 2) - 4; % Y轴位置

                    % 显示图标
                    icon_handle(end + 1) = image(app.Coordinate, 'CData', iconDataResized, ...
                        'XData', [positionX positionX + 6], ...
                        'YData', [positionY positionY + 8], ...
                        'AlphaData', alphaDataResized);
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            pushLogMsg(app, '正在初始化应用...');

            % add the required paths
            addpath(fullfile('..', '..', '..', 'ShareFolder', 'comtools'));
            addpath(genpath(fullfile('..', '..', '..', 'ShareFolder', 'simlib')));
            addpath(fullfile('..', 'Signal Generator'));
            addpath(genpath(fullfile('..', 'Signal Detector')));

            % load thresholds
            tmp = load(fullfile('data', 'thresholds_default.mat'));
            app.thresholds = tmp.thresholds;

            % initialize the node positions
            app.set_rec_node_positionButtonPushed(app);
            
            % re-color the linear gauge
            % for ED
            threshold = app.thresholds(1);
            app.test_statistics_ED.Limits = [0.9, 1.1]*threshold;
            app.test_statistics_ED.ScaleColors = [0 1 0; 1 0 0]; % RGB for green and red
            app.test_statistics_ED.ScaleColorLimits = [0.9 1; 1 1.1]*threshold;
            % for MME
            threshold = app.thresholds(2);
            app.test_statistics_MME.Limits = [0.9, 1.1]*threshold;
            app.test_statistics_MME.ScaleColors = [0 1 0; 1 0 0]; % RGB for green and red
            app.test_statistics_MME.ScaleColorLimits = [0.9 1; 1 1.1]*threshold;
            % for GLRT
            threshold = app.thresholds(3);
            app.test_statistics_GLRT.Limits = [0, 2]*threshold;
            app.test_statistics_GLRT.ScaleColors = [0 1 0; 1 0 0]; % RGB for green and red
            app.test_statistics_GLRT.ScaleColorLimits = [0 1; 1 2]*threshold;

            
            % refresh figure
            refreshCoordinate(app);

            pushLogMsg(app, '初始化完成');
        end

        % Button pushed function: setInputXY
        function UIAxesButtonDown(app, event)
            app.source_node_position = [app.input_x.Value, app.input_y.Value];
            refreshCoordinate(app);
        end

        % Value changed function: setClickXY
        function setClickXYValueChanged(app, event)

            % refresh the text on the button
            app.is_source_node_position_ready = ~app.setClickXY.Value;
            if (~app.is_source_node_position_ready)
                app.setClickXY.Text = '正在选取信号源位置';
            else
                app.setClickXY.Text = '点击选定信号源坐标';
            end

        end

        % Button down function: Coordinate
        function CoordinateButtonDown(app, event)

            % enter the click-to-select mode, for source node position
            if ~app.is_source_node_position_ready
                % Get the current point
                clickPoint = app.Coordinate.CurrentPoint;
                x = clickPoint(1,1);
                y = clickPoint(1,2);

                % write property
                app.source_node_position = [x, y];

                % show x & y in the left panel
                app.input_x.Value = x;
                app.input_y.Value = y;

                % remove the previous source node position in figure
                delete(app.sourcePointHandles);

                % Plot point and store the handle
                hold(app.Coordinate, 'on'); % Keep previous points visible
                app.sourcePointHandles = plotSourceIcon(app, app.source_node_position);
                hold(app.Coordinate, 'off');
            end

            % enter the click-to-select mode, for receive node position
            if ~app.is_rec_node_position_ready && app.rec_node_num_valid < app.rec_node_num
                % Get the current point
                clickPoint = app.Coordinate.CurrentPoint;
                x = clickPoint(1,1);
                y = clickPoint(1,2);

                % write property
                app.rec_node_position(end+1, :) = [x, y];

                % Plot point and store the handle
                hold(app.Coordinate, 'on'); % Keep previous points visible
                % app.recPointHandles(end + 1) = plot(app.Coordinate, x, y,  '^r', 'MarkerFaceColor', 'r', 'MarkerSize', 6);
                app.recPointHandles(end + 1) = plotRecNodeIcon(app, [x, y]);
                hold(app.Coordinate, 'off');
                
                % increase counter by 1
                app.rec_node_num_valid = app.rec_node_num_valid + 1;

            end
        end

        % Value changed function: set_click_rec_node_position
        function set_click_rec_node_positionValueChanged(app, event)

            % refresh the text on the button
            app.is_rec_node_position_ready = ~app.set_click_rec_node_position.Value;
            if (~app.is_rec_node_position_ready)
                
                % set the rec_node_num
                app.rec_node_num = str2double(app.input_rec_node_num.Value);
                
                % remove the previous rec node position in figure
                delete(app.recPointHandles);
                app.rec_node_position = [];
                
                % initialize the counter for rec node
                app.rec_node_num_valid = 0;
                
                app.set_click_rec_node_position.Text = '正在选取感知节点位置';
            else
                app.set_click_rec_node_position.Text = '点击选定感知节点坐标';
            end

        end

        % Button pushed function: set_rec_node_position
        function set_rec_node_positionButtonPushed(app, event)

            try
                drone_positions = droneFormation([app.input_center_x.Value, app.input_center_y.Value, 0], ...
                    str2double(app.input_rec_node_num_2.Value), ...
                    app.input_rec_node_range.Value, ...
                    app.input_formation.Value, ...
                    app.input_rec_node_dir.Value);
                app.rec_node_position = drone_positions(:, 1:2);
                app.rec_node_num = str2double(app.input_rec_node_num_2.Value);
                pushLogMsg(app, ['新构型设定成功！'])
            catch err
                pushLogMsg(app, ['无效的构型设定：', err.message])
            end

            % refresh figure
            refreshCoordinate(app);
        end

        % Callback function: single_simulate
        function single_simulateClicked(app, event)
            pushLogMsg(app, '正在进行单次模拟实验...');
            singleSimulation(app);
            pushLogMsg(app, '实验完成！');
        end

        % Callback function: continue_simulate
        function continue_simulateClicked(app, event)
            app.simulation_running = true;
            pushLogMsg(app, '正在进行连续模拟实验...（点击按钮停止）');
            counter = 0;
            num_detect = zeros(3, 1);
            while app.simulation_running
                singleSimulation(app);
                counter = counter + 1;
                num_detect = num_detect + (app.res_ts >= app.thresholds);
                app.pd = num_detect / counter;
                app.Pd_ED.Text   = sprintf('%.2f%%', app.pd(1)*100);
                app.Pd_MME.Text  = sprintf('%.2f%%', app.pd(2)*100);
                app.Pd_GLRT.Text = sprintf('%.2f%%', app.pd(3)*100);
                pause(0.3);
                drawnow;
            end
            pushLogMsg(app, '实验已停止！');
        end

        % Button pushed function: set_threshold_file_path
        function set_threshold_file_pathButtonPushed(app, event)
            % 使用uigetfile打开文件选择对话框
            [file,path] = uigetfile('*.mat', 'Select a MATLAB code file');

            % 如果用户没有取消，则更新文本框的内容
            if isequal(file,0)
                app.threshold_file_path.Value = 'User selected Cancel';
            else
                app.threshold_file_path.Value = fullfile(path, file); % 将完整的文件路径显示在文本框中
            end
            app.threshold_file_path.Tooltip = fullfile(path, file);
        end

        % Button pushed function: auto_set_threshold
        function auto_set_thresholdButtonPushed(app, event)

            % get the current simulation parameter
            current_sim_param = struct(...
                'rec_node_num', app.rec_node_num,...
                'sample_rate', app.input_sample_rate.Value, ...
                'observe_time', app.input_obs_time.Value, ...
                'baud_rate', app.input_baud_rate.Value, ...
                'fc', app.input_fc.Value, ...
                'modu_type', app.input_mod_type.Value, ...
                'noise_fluc', app.noise_fluc.Value, ...
                'Pf', app.input_pf.Value);

            file_name_wharsh = fullfile('data/', ['thresholds_', matlabStruct2HashStr(app, current_sim_param), '.mat']);

            if (exist(file_name_wharsh, 'file') == 2 && ~app.force_monte_carlo.Value)  % if thresholds have been stored
                tmp = load(file_name_wharsh);
                app.thresholds = tmp.thresholds;
                pushLogMsg(app, '当前环境下的门限值已有记录，系统已直接加载！');
            else
                thresholds = findThresholdsByMonteCarlo(app);  % if thresholds have not been stored
                save(file_name_wharsh, 'thresholds');
                pushLogMsg(app, '门限值已找到！');
                pushLogMsg(app, ['最新门限数据已保存在', file_name_wharsh, '文件中！']);
            end   
        end

        % Callback function: stop_simulate
        function stop_simulateClicked(app, event)
            app.simulation_running = false;
        end

        % Button pushed function: input_threshold_from_localfile
        function input_threshold_from_localfileButtonPushed(app, event)
            if (exist(app.threshold_file_path.Value, 'file') == 2)
                tmp = load(app.threshold_file_path.Value);
                app.thresholds = tmp.thresholds;
                pushLogMsg(app, '加载文件成功!');
            else
                pushLogMsg(app, ['无法加载指定的文件：', app.threshold_file_path.Value]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.single_simulate = uipushtool(app.Toolbar);
            app.single_simulate.ClickedCallback = createCallbackFcn(app, @single_simulateClicked, true);
            app.single_simulate.Icon = fullfile(pathToMLAPP, 'icons', 'one-step-play.png');
            app.continue_simulate = uipushtool(app.Toolbar);
            app.continue_simulate.ClickedCallback = createCallbackFcn(app, @continue_simulateClicked, true);
            app.continue_simulate.Icon = fullfile(pathToMLAPP, 'icons', 'continue.png');
            app.stop_simulate = uipushtool(app.Toolbar);
            app.stop_simulate.ClickedCallback = createCallbackFcn(app, @stop_simulateClicked, true);
            app.stop_simulate.Icon = fullfile(pathToMLAPP, 'icons', 'stop.png');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '靶标节点、感知节点坐标示意图';
            app.Coordinate = uiaxes(app.Panel);
            xlabel(app.Coordinate, '经度坐标（km）')
            ylabel(app.Coordinate, '纬度坐标（km）')
            zlabel(app.Coordinate, 'Z')
            app.Coordinate.XLim = [-50 50];
            app.Coordinate.YLim = [-50 50];
            app.Coordinate.ButtonDownFcn = createCallbackFcn(app, @CoordinateButtonDown, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '分布式节点协同感知演示系统';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '辐射源参数设置';
            app.TabGroup = uitabgroup(app.Panel_2);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '发射功率';
            app.Label_6 = uilabel(app.Tab);
            app.Label_6.Text = '参考信噪比 (dB)：';
            app.input_ref_SNR = uislider(app.Tab);
            app.input_ref_SNR.Limits = [-10 20];
            app.input_ref_SNR.Value = 2;
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '位置';
            app.Panel_3 = uipanel(app.Tab_2);
            app.Panel_3.Title = '按以下输入设定';
            app.Label_2 = uilabel(app.Panel_3);
            app.Label_2.Text = '经度坐标：';
            app.input_x = uieditfield(app.Panel_3, 'numeric');
            app.input_x.Limits = [-50 50];
            app.setInputXY = uibutton(app.Panel_3, 'push');
            app.setInputXY.ButtonPushedFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.setInputXY.Text = '【确认】加载';
            app.Label_3 = uilabel(app.Panel_3);
            app.Label_3.Text = '纬度坐标：';
            app.input_y = uieditfield(app.Panel_3, 'numeric');
            app.input_y.Limits = [-50 50];
            app.Panel_4 = uipanel(app.Tab_2);
            app.Panel_4.Title = '直接在图中选定';
            app.setClickXY = uibutton(app.Panel_4, 'state');
            app.setClickXY.ValueChangedFcn = createCallbackFcn(app, @setClickXYValueChanged, true);
            app.setClickXY.Text = '点击选定信号源坐标';
            app.Label_5 = uilabel(app.Panel_4);
            app.Label_5.Text = {'操作步骤：'; '1. 点击下方图标，使颜色变深'; '2. 使用鼠标左键选定图中坐标'; '3. 再次点击下方图标，使颜色变浅'};
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '其他';
            app.HzEditFieldLabel = uilabel(app.Tab_3);
            app.HzEditFieldLabel.Text = '采样率 (Hz)：';
            app.input_sample_rate = uieditfield(app.Tab_3, 'numeric');
            app.input_sample_rate.Limits = [100 Inf];
            app.input_sample_rate.ValueDisplayFormat = '%.0f';
            app.input_sample_rate.Value = 100000;
            app.HzEditField_2Label = uilabel(app.Tab_3);
            app.HzEditField_2Label.Text = '符号率 (Hz)：';
            app.input_baud_rate = uieditfield(app.Tab_3, 'numeric');
            app.input_baud_rate.Limits = [100 Inf];
            app.input_baud_rate.ValueDisplayFormat = '%.0f';
            app.input_baud_rate.Value = 20000;
            app.sEditFieldLabel = uilabel(app.Tab_3);
            app.sEditFieldLabel.Text = '观测时间 (s)：';
            app.input_obs_time = uieditfield(app.Tab_3, 'numeric');
            app.input_obs_time.Limits = [0 100];
            app.input_obs_time.Value = 0.1;
            app.HzEditField_3Label = uilabel(app.Tab_3);
            app.HzEditField_3Label.Text = '载波频率 (Hz)：';
            app.input_fc = uieditfield(app.Tab_3, 'numeric');
            app.input_fc.Limits = [1000000 Inf];
            app.input_fc.Value = 1420000000;
            app.Label_14 = uilabel(app.Tab_3);
            app.Label_14.Text = '调制方式：';
            app.input_mod_type = uidropdown(app.Tab_3);
            app.input_mod_type.Items = {'BPSK', 'QPSK', '8PSK', '16QAM', '64QAM', 'PAM4', 'B-FM'};
            app.input_mod_type.Value = 'QPSK';
            app.Panel_5 = uipanel(app.UIFigure);
            app.Panel_5.Title = '感知节点参数设置';
            app.TabGroup2 = uitabgroup(app.Panel_5);
            app.Tab_7 = uitab(app.TabGroup2);
            app.Tab_7.Title = '实时状态';
            app.SNRs = uilabel(app.Tab_7);
            app.SNRs.Text = '信噪比';
            app.Tab_4 = uitab(app.TabGroup2);
            app.Tab_4.Title = '节点构型';
            app.Panel_6 = uipanel(app.Tab_4);
            app.Panel_6.Title = '按以下参数设定';
            app.Label_7 = uilabel(app.Panel_6);
            app.Label_7.Text = '预设构型：';
            app.input_formation = uidropdown(app.Panel_6);
            app.input_formation.Items = {'Semicircle', 'Triangle', 'Diamond', 'Arrow', 'Line', 'Random'};
            app.input_formation.Value = 'Semicircle';
            app.Label_8 = uilabel(app.Panel_6);
            app.Label_8.Text = '节点数量：';
            app.input_rec_node_num_2 = uidropdown(app.Panel_6);
            app.input_rec_node_num_2.Items = {'3', '4', '5', '6', '7', '8', '9', '10'};
            app.input_rec_node_num_2.Value = '8';
            app.kmLabel = uilabel(app.Panel_6);
            app.kmLabel.Text = '缩放尺度(km)：';
            app.input_rec_node_range = uieditfield(app.Panel_6, 'numeric');
            app.input_rec_node_range.Limits = [0 Inf];
            app.input_rec_node_range.Value = 50;
            app.Label_9 = uilabel(app.Panel_6);
            app.Label_9.Text = '朝向：';
            app.input_rec_node_dir = uieditfield(app.Panel_6, 'numeric');
            app.input_rec_node_dir.Value = 180;
            app.Label_10 = uilabel(app.Panel_6);
            app.Label_10.Text = '中心经度坐标：';
            app.input_center_x = uieditfield(app.Panel_6, 'numeric');
            app.Label_11 = uilabel(app.Panel_6);
            app.Label_11.Text = '中心纬度坐标：';
            app.input_center_y = uieditfield(app.Panel_6, 'numeric');
            app.set_rec_node_position = uibutton(app.Panel_6, 'push');
            app.set_rec_node_position.ButtonPushedFcn = createCallbackFcn(app, @set_rec_node_positionButtonPushed, true);
            app.set_rec_node_position.Text = '【确认】加载';
            app.Panel_7 = uipanel(app.Tab_4);
            app.Panel_7.Title = '直接在图中选定';
            app.Label_12 = uilabel(app.Panel_7);
            app.Label_12.Text = {'操作步骤：'; '1. 首先选择节点数量'; '2. 点击下方图标，使颜色变深'; '3. 使用鼠标坐标依次选定图中坐标'; '4. 再次点击下方图标，使颜色变深'};
            app.Label_13 = uilabel(app.Panel_7);
            app.Label_13.Text = '节点数量：';
            app.input_rec_node_num = uidropdown(app.Panel_7);
            app.input_rec_node_num.Items = {'3', '4', '5', '6', '7', '8', '9', '10'};
            app.input_rec_node_num.Value = '3';
            app.set_click_rec_node_position = uibutton(app.Panel_7, 'state');
            app.set_click_rec_node_position.ValueChangedFcn = createCallbackFcn(app, @set_click_rec_node_positionValueChanged, true);
            app.set_click_rec_node_position.Text = '点击选定感知节点坐标';
            app.Tab_5 = uitab(app.TabGroup2);
            app.Tab_5.Title = '方法门限';
            app.Label_16 = uilabel(app.Tab_5);
            app.Label_16.Text = '从文件导入：';
            app.threshold_file_path = uieditfield(app.Tab_5, 'text');
            app.set_threshold_file_path = uibutton(app.Tab_5, 'push');
            app.set_threshold_file_path.ButtonPushedFcn = createCallbackFcn(app, @set_threshold_file_pathButtonPushed, true);
            app.set_threshold_file_path.Text = '选择文件 (.mat)';
            app.auto_set_threshold = uibutton(app.Tab_5, 'push');
            app.auto_set_threshold.ButtonPushedFcn = createCallbackFcn(app, @auto_set_thresholdButtonPushed, true);
            app.auto_set_threshold.Text = '自动生成';
            app.Label_17 = uilabel(app.Tab_5);
            app.Label_17.Text = '仿真次数：';
            app.input_MC = uieditfield(app.Tab_5, 'numeric');
            app.input_MC.Limits = [100 Inf];
            app.input_MC.Value = 100;
            app.Label_18 = uilabel(app.Tab_5);
            app.Label_18.Text = '虚警率：';
            app.input_pf = uieditfield(app.Tab_5, 'numeric');
            app.input_pf.Limits = [1e-08 0.2];
            app.input_pf.Value = 0.01;
            app.input_threshold_from_localfile = uibutton(app.Tab_5, 'push');
            app.input_threshold_from_localfile.ButtonPushedFcn = createCallbackFcn(app, @input_threshold_from_localfileButtonPushed, true);
            app.input_threshold_from_localfile.Text = '【确认】导入';
            app.force_monte_carlo = uicheckbox(app.Tab_5);
            app.force_monte_carlo.Text = '强制重新生成门限';
            app.Tab_6 = uitab(app.TabGroup2);
            app.Tab_6.Title = '底噪';
            app.EditFieldLabel = uilabel(app.Tab_6);
            app.EditFieldLabel.Text = '噪声参数：';
            app.noise_fluc = uieditfield(app.Tab_6, 'numeric');
            app.noise_fluc.Limits = [0 Inf];
            app.noise_fluc.Value = 0.5;
            app.Panel_8 = uipanel(app.UIFigure);
            app.Panel_8.Title = '分布式节点感知效果演示';
            app.test_statistics_ED = uigauge(app.Panel_8, 'linear');
            app.EDLabel = uilabel(app.Panel_8);
            app.EDLabel.Text = 'ED';
            app.test_statistics_MME = uigauge(app.Panel_8, 'linear');
            app.MMELabel = uilabel(app.Panel_8);
            app.MMELabel.Text = 'MME';
            app.GLRTLabel = uilabel(app.Panel_8);
            app.GLRTLabel.Text = 'GLRT';
            app.test_statistics_GLRT = uigauge(app.Panel_8, 'linear');
            app.Label_19 = uilabel(app.Panel_8);
            app.Label_19.Text = '方法名称';
            app.Label_20 = uilabel(app.Panel_8);
            app.Label_20.Text = '检验统计量';
            app.Label_21 = uilabel(app.Panel_8);
            app.Label_21.Text = '检测概率 (统计)';
            app.Pd_ED = uilabel(app.Panel_8);
            app.Pd_ED.Text = '0%';
            app.Pd_MME = uilabel(app.Panel_8);
            app.Pd_MME.Text = '0%';
            app.Pd_GLRT = uilabel(app.Panel_8);
            app.Pd_GLRT.Text = '0%';
            app.Panel_9 = uipanel(app.UIFigure);
            app.Panel_9.Title = '日志';
            app.log_area = uitextarea(app.Panel_9);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demoDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end